classdef bpmguncel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image5                    matlab.ui.control.Image
        Image                     matlab.ui.control.Image
        Label2                    matlab.ui.control.Label
        RPMLabel                  matlab.ui.control.Label
        BPMLabel                  matlab.ui.control.Label
        LableLabel                matlab.ui.control.Label
        EditOrderEditField        matlab.ui.control.NumericEditField
        EditOrderEditFieldLabel   matlab.ui.control.Label
        LowpassfcEditField        matlab.ui.control.NumericEditField
        LowpassfcEditFieldLabel   matlab.ui.control.Label
        HighpassfcEditField       matlab.ui.control.NumericEditField
        HighpassfcEditFieldLabel  matlab.ui.control.Label
        StopButton                matlab.ui.control.Button
        StartButton               matlab.ui.control.Button
        Image3                    matlab.ui.control.Image
        Image4                    matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
    end


     properties (Access = private)
        a 
        f
        f1
        Y
        Y1
        P1
        P2
        P3
        P4
        filteredPlot
        rawPlot
        serialport
        
        fs = 8900;
        fc1 = 30;
        fc2 = 1500;
        T = 1/8900;
        n = 1;
        m = 1;
        m1 = 1;
        plotWindow = 2000;
        IsRunning = false;
        Timer
        Data
        ffData
        fdata 
        coefficient_1
        coefficient_2
        fc3=30
        bpm
        rpm % RPM değerini tutmak için bir özellik
        coefficient_3
        coefficient_4
        orderOfFilter=4;
        end

    methods (Access = private)
        
        function timerCallback(app)
    try
        % Gelen veriyi oku
        data = readSensorData(app);
        
        % Buffer uzunluğunu kontrol et
        maxBufferSize = 500; % Maksimum tutulacak veri sayısı
        if length(app.DataBuffer) >= maxBufferSize
            app.DataBuffer(1) = []; % İlk öğeyi kaldır
        end
        app.DataBuffer(end+1) = data; % Yeni veriyi ekle

        % Grafik güncellemesi
        if isvalid(app.UIAxes) % UI'nın hâlâ geçerli olduğunu kontrol et
            plot(app.UIAxes, app.DataBuffer, 'LineWidth', 1.5); % Grafik çizimi
            title(app.UIAxes, 'Gerçek Zamanlı Veri'); % Başlık
            xlabel(app.UIAxes, 'Zaman'); % X ekseni etiketi
            ylabel(app.UIAxes, 'Amplitüd'); % Y ekseni etiketi
        end
    catch e
        % Hata yönetimi
        disp('timerCallback hata verdi:');
        disp(e.message); % Hata mesajını görüntüle
    end
end

 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Timer Nesnesini Oluştur
    app.t = timer('ExecutionMode', 'fixedSpacing', ... % fixedSpacing ile gecikme minimize edilir
                  'Period', 0.1, ... % 0.1 saniye periyot daha stabil bir çalışma sağlar
                  'TimerFcn', @(~,~)timerCallback(app));

    % Timer Nesnesinin Çalıştığını Doğrula
    try
        start(app.t); % Timer'ı başlat
        disp('Timer başlatıldı.');
    catch e
        disp('Timer başlatılamadı:');
        disp(e.message); % Hata mesajını görüntüle
    end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.IsRunning = true;
            app.a = serialport("COM11", 9600);
            configureTerminator(app.a, "CR/LF");
            flush(app.a);
            app.a.UserData = struct("Data", [], "Order", 1, "fData", [],"ffData",[]);
            app.IsRunning = true;
            
            while app.IsRunning && app.a.UserData.Order < 100000
                     app.Data = readline(app.a);
                    app.a.UserData.Data(end+1) = str2double(app.Data);
                    app.a.UserData.Order = app.a.UserData.Order + 1;
                if mod(app.a.UserData.Order, 10) == 0
                    configureCallback(app.a, "off");

                    plot(app.UIAxes, app.a.UserData.Data(max(1,end-app.plotWindow+1):end))
                      [app.coefficient_1, app.coefficient_2] = butter(4, app.fc3/(app.fs/2), 'high');
        app.a.UserData.ffData = filter(app.coefficient_1, app.coefficient_2, app.a.UserData.Data(1:end));
                    % Band-pass filter oluşturma
[app.coefficient_3, app.coefficient_4] = butter(app.orderOfFilter, [app.fc1 / (app.fs / 2), app.fc2 / (app.fs / 2)], 'bandpass');

% Kazanç ekleme
gain = 25; % Filtrenin çıkış genliğini artırmak için bir kazanç faktörü
app.coefficient_3 = app.coefficient_3 * gain;

% Filtreleme işlemi
app.a.UserData.fData = filter(app.coefficient_3, app.coefficient_4, app.a.UserData.Data);
                    
                    plot(app.UIAxes_2, app.a.UserData.fData(max(1, end-app.plotWindow+1):end) * 3);
                    drawnow;
                    
                    configureTerminator(app.a, "CR/LF");

                end
                 if mod(app.a.UserData.Order,500)==0
                     configureCallback(app.a, "off");

                     app.Y = fft(app.a.UserData.ffData(end-499+1:end));
                     app.P2 = abs(app.Y / 500); % Use 400 for normalization
                     app.f = app.fs * (0:(length(app.Y)/2))/length(app.Y); % Adjust frequency axis
                     app.P1 = app.P2(1:length(app.Y)/2+1);
                     app.P1(2:end-1) = 2 * app.P1(2:end-1);
                     plot(app.UIAxes_3,app.f,app.P1,'Color','r');
                     set(gca,'ylim',[0,10]);

                     app.Y1 = fft(app.a.UserData.fData(end-499+1:end));
                     app.P3 = abs(app.Y1 / 500); % Use 400 for normalization
                     app.f1 = app.fs * (0:(length(app.Y1)/2))/length(app.Y1); % Adjust frequency axis
                     app.P4 = app.P3(1:length(app.Y1)/2+1);
                     app.P4(2:end-1) = 2 * app.P4(2:end-1);
                     plot(app.UIAxes_4, app.f1,app.P4,'Color','r');
                     set(gca,'ylim',[0,15]);

                     grid on;
                     app.m=app.m+500;
                     configureTerminator(app.a,"CR/LF");
                 end
if length(app.a.UserData.Data) >= 890 % Yeterli veri birikmiş mi kontrol et
    % --- Kalp Atışı (BPM) Hesaplama ---
    % Zaman damgası
    timeAtPointBPM = toc; % Geçen zamanı ölç

    % Voltaj aralığına göre tepe noktalarını bul
    validPeaks = app.a.UserData.Data(end-889:end); % Son 890 veri
    peakCount = sum(validPeaks > 0.4 & validPeaks < 1.7); % 0.5-1.7 volt aralığında olan değerleri say

       % BPM hesaplama
    if peakCount > 0 && timeAtPointBPM > 0
         app.bpm = (peakCount / timeAtPointBPM) / 8; % BPM hesaplama
         if app.bpm > 150 
             disp(['BPM exceeded limit. Retaining previous BPM: ', num2str(int16(app.bpm))]);
             app.bpm = app.LableLabel.Text; % Önceki değeri koru
         else
             disp(['BPM: ', num2str(int16(app.bpm))]); % Konsola yazdır
             app.LableLabel.Text = num2str(int16(app.bpm)); % BPM etiketi güncelle
             tic; % Zamanlayıcıyı sıfırla
         end
    else
        % Eğer yeterli tepe yoksa önceki BPM değerini koru
        disp(['No new valid peaks detected. Retaining previous BPM: ', num2str(int16(app.bpm))]);
        app.LableLabel.Text = num2str(int16(app.bpm));
    end
    % --- Nefes Alış-Veriş (RPM) Hesaplama ---
    % FFT ile frekans analizi
    fftData = fft(app.a.UserData.Data(end-889:end)); % Son 890 veri üzerinden FFT
    fftMagnitude = abs(fftData / length(fftData)); % FFT genlik spektrumu
    fftMagnitude = fftMagnitude(1:floor(length(fftData)/2)); % Pozitif frekanslar
    frequencyAxis = app.fs * (0:(length(fftMagnitude)-1)) / length(fftData); % Frekans ekseni

      % Genlik threshold kontrolü
    fftThresholdMin = 0.007; % Eşik değer alt sınırı
    fftThresholdMax = 0.009; % Eşik değer üst sınırı
    validIndices = fftMagnitude > fftThresholdMin & fftMagnitude < fftThresholdMax; % Eşik aralığını geçen frekansları bul

    if any(validIndices)
        % Dominant frekansı bul
        [~, dominantIndex] = max(fftMagnitude(validIndices)); % Maksimum genlik indeksini bul
        dominantFrequencies = frequencyAxis(validIndices); % Threshold sonrası frekans ekseni
        dominantFrequency = dominantFrequencies(dominantIndex); % Dominant frekansı al

        % RPM hesapla
        app.rpm = dominantFrequency/200; % Hz'yi RPM'e çevir (dakikaya çevirme)
        disp(['RPM: ', num2str(int16(app.rpm))]); % Konsola yazdır
        app.Label2.Text = num2str(int16(app.rpm)); % RPM etiketi güncelle
    else
        % Eğer threshold'u geçen frekans yoksa önceki RPM'i koru
        disp(['No significant frequency detected. Retaining previous RPM: ', num2str(int16(app.rpm))]);
        app.Label2.Text = num2str(int16(app.rpm));
    
    end
end


            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.IsRunning=false;
            delete(app.a);
        end

        % Value changed function: LowpassfcEditField
        function LowpassfcEditFieldValueChanged(app, event)
            b=app.LowpassfcEditField.Value;
            app.fc2=b;
        end

        % Value changed function: HighpassfcEditField
        function HighpassfcEditFieldValueChanged(app, event)
             c= app.HighpassfcEditField.Value;
             app.fc1=c;
        end

        % Value changed function: EditOrderEditField
        function EditOrderEditFieldValueChanged(app, event)
            j = app.EditOrderEditField.Value;
            app.orderOfFilter=j;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Filtered Signal')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Raw Signal FFT')
            xlabel(app.UIAxes_3, 'Frequency')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Filtered Signal FFT')
            xlabel(app.UIAxes_4, 'Frequency')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Raw Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'frame_leftbot.png');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Adsız tasarım (3)-Photoroom.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'frame_rightbot.png');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.HighpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.HighpassfcEditFieldLabel.Text = 'High pass fc';
            app.HighpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @HighpassfcEditFieldValueChanged, true);
            app.HighpassfcEditField.Value = 30;
            app.LowpassfcEditFieldLabel = uilabel(app.UIFigure);
            app.LowpassfcEditFieldLabel.Text = 'Low pass fc';
            app.LowpassfcEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowpassfcEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassfcEditFieldValueChanged, true);
            app.LowpassfcEditField.Value = 200;
            app.EditOrderEditFieldLabel = uilabel(app.UIFigure);
            app.EditOrderEditFieldLabel.Text = 'Edit Order';
            app.EditOrderEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditOrderEditField.ValueChangedFcn = createCallbackFcn(app, @EditOrderEditFieldValueChanged, true);
            app.EditOrderEditField.Value = 4;
            app.LableLabel = uilabel(app.UIFigure);
            app.LableLabel.Text = 'Lable';
            app.BPMLabel = uilabel(app.UIFigure);
            app.BPMLabel.Text = 'BPM';
            app.RPMLabel = uilabel(app.UIFigure);
            app.RPMLabel.Text = 'RPM';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image-Photoroom.png');
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'lung.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bpmguncel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end